classdef PCFramework < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ExampleTab                    matlab.ui.container.Tab
        RunButton_ex                  matlab.ui.control.StateButton
        PlotButtonGroup_ex            matlab.ui.container.ButtonGroup
        PriorButton_ex                matlab.ui.control.RadioButton
        PhiButton_ex                  matlab.ui.control.RadioButton
        EpsilonButton_ex              matlab.ui.control.RadioButton
        ShowTrialLabel                matlab.ui.control.Label
        ShowLayerSpinner_ex           matlab.ui.control.Spinner
        TextArea                      matlab.ui.control.TextArea
        UIAxes_ex                     matlab.ui.control.UIAxes
        AdvancedTab_2                 matlab.ui.container.Tab
        RunButton_adv                 matlab.ui.control.StateButton
        Description_adv               matlab.ui.control.TextArea
        TotalLayersSpinner_3Label     matlab.ui.control.Label
        TotalLayersSpinner_adv        matlab.ui.control.Spinner
        LesionLabel                   matlab.ui.control.Label
        LeizureListBox_adv            matlab.ui.control.ListBox
        SigmaLRSpinner_2Label         matlab.ui.control.Label
        SigmaLRSpinner_adv            matlab.ui.control.Spinner
        PriorMeanInputEditFieldLabel  matlab.ui.control.Label
        PriorMeanInputEditField_adv   matlab.ui.control.EditField
        TimeUnitsSpinner_3Label       matlab.ui.control.Label
        TimeUnitsSpinner_adv          matlab.ui.control.Spinner
        tLabel                        matlab.ui.control.Label
        tSpinner_adv                  matlab.ui.control.Spinner
        TrialsSpinnerLabel            matlab.ui.control.Label
        TrialsSpinner_adv             matlab.ui.control.Spinner
        UIAxes_adv                    matlab.ui.control.UIAxes
        ThetaLRSpinnerLabel           matlab.ui.control.Label
        ThetaLRSpinner_adv            matlab.ui.control.Spinner
        PriorMeanLRSpinnerLabel       matlab.ui.control.Label
        PriorMeanLRSpinner_adv        matlab.ui.control.Spinner
        ShowPlotButtonGroup_adv       matlab.ui.container.ButtonGroup
        PriorButton_adv               matlab.ui.control.RadioButton
        PhiButton_adv                 matlab.ui.control.RadioButton
        EpsilonButton_adv             matlab.ui.control.RadioButton
        ObservedInputEditFieldLabel   matlab.ui.control.Label
        ObservedInputEditField_adv    matlab.ui.control.EditField
        PriorVarianceLabel            matlab.ui.control.Label
        PriorSigmaEditField_adv       matlab.ui.control.EditField
        ObservationFunctionDropDownLabel  matlab.ui.control.Label
        ObservationFunctionDropDown_adv  matlab.ui.control.DropDown
        ShowLayerSpinnerLabel         matlab.ui.control.Label
        ShowLayerSpinner_adv          matlab.ui.control.Spinner
        ShowTrialLabel_2              matlab.ui.control.Label
        ShowTrialSpinner_adv          matlab.ui.control.Spinner
        PressRunLamp                  matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        %example settings
        result_images
        currentLayer 
        currentTrial
        out_priors_ex
        out_phis_ex
        out_epsilons_ex
        image_prior_ex
        image_phi_ex
        image_epsilon_ex
        
        %advanced inputs
        input_prior
        input_observed
        input_sigma
        totalLayers
        lesion
        observation_funciton
        sigmaLR
        thetaLR
        priorMeanLR
        timeUnits
        totalTrials
        deltaT
        
        %outputs
        out_priors
        out_phis
        out_epsilons
        out_sigmas
    end

    
    methods (Access = private)     
        function plotAdvanedResults(app, priorMeanData, phiData, epsilonData)
            layer = app.ShowLayerSpinner_adv.Value;
            trial = app.ShowTrialSpinner_adv.Value;
            whichPlot = app.ShowPlotButtonGroup_adv.SelectedObject.Text;
            
            if strcmp(whichPlot, "Epsilon")
                title(app.UIAxes_adv, {"Epsilon"})
                xlabel(app.UIAxes_adv, "Time Units")
                ylabel(app.UIAxes_adv, "Epsilon")
                x = epsilonData{trial}{1};
                y = epsilonData{trial}{2}{layer};
                dn = '\epsilon_{' ;
            elseif strcmp(whichPlot, "Phi")
                title(app.UIAxes_adv, {"Phi"})
                xlabel(app.UIAxes_adv, "Time Units")
                ylabel(app.UIAxes_adv, "Posterior P(\Phi|u)")
                if layer == 1
                    x = phiData{trial}{1};
                    y = phiData{trial}{2}{layer+1}.*0;
                else
                    x = phiData{trial}{1};
                    y = phiData{trial}{2}{layer};
                end
                dn = '\phi_{' ;
            else
                title(app.UIAxes_adv, "Prior ")
                xlabel(app.UIAxes_adv, {"Trials"})
                ylabel(app.UIAxes_adv, ["Prior Mean"; ""])
                x = priorMeanData{trial}{1};
                y = priorMeanData{trial}{2};
                dn = 'prior_{';
            end
            numberOfInputs = length(app.input_observed{1});
            cla(app.UIAxes_adv)
            for observation = 1:numberOfInputs
                hold(app.UIAxes_adv, 'on' )
                plot(app.UIAxes_adv,x, y(observation, :), ... 
                     'DisplayName', [dn num2str(layer) ', ' num2str(observation) '}'])
            end
            legend(app.UIAxes_adv)
            hold(app.UIAxes_adv, 'off' )
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function test_BW_images(app, event)
            pixels = 2;
            %color = gray(100);
            %an is a 3d matrix, the first dimension encodes the layer
            
            %%% here comes the backend function 
            matrix = zeros(pixels, pixels, 2);
            matrix(:,:,1) = rand(pixels,pixels)*300 - 100;
            matrix(:,:,2) = rand(pixels,pixels)*300 - 100;
            matrix(:,:,1) = [250,90;30,200];
            %%%%
            o = ones(100);
            matrix = [o*matrix(1,1,1), o*matrix(1,2,1); o*matrix(2,1,1), o*matrix(2,2,1)];
            result = matrixToUIImage(app,matrix);
            app.Image_ex.ImageSource = result;
            
        end

        % Callback function
        function LayerSliderValueChanged(app, event)
            value = app.LayerSlider.Value;
            if (value <= app.numberOfLayers)
                app.currentLayer = value;
            end
            r = round(rand)+1;
            app.Image_ex.ImageSource = matrixToUIImage(app,app.result_images(:,:,r));
        end

        % Callback function
        function InputMatrixEditFieldValueChanged(app, event)
            value = app.InputMatrixEditField.Value;
            if ismatrix(value)
                app.input_matrix = value;
            end
            
        end

        % Callback function
        function TotalLayersSpinner_2ValueChanged(app, event)
            value = app.TotalLayersSpinner_2.Value;
            app.numberOfLayers_advanced = value;
        end

        % Value changed function: SigmaLRSpinner_adv
        function SigmaLRSpinnerValueChanged(app, event)
            value = app.SigmaLRSpinner_adv.Value;
            app.sigmaLR = value;
        end

        % Value changed function: TimeUnitsSpinner_adv
        function TimeUnitsSpinner_advValueChanged(app, event)
            value = app.TimeUnitsSpinner_adv.Value;
            app.timeUnits = value;
        end

        % Value changed function: tSpinner_adv
        function tSpinner_advValueChanged(app, event)
            value = app.tSpinner_adv.Value;
            app.deltaT = value;
        end

        % Value changed function: TrialsSpinner_adv
        function TrialsSpinner_advValueChanged(app, event)
            value = app.TrialsSpinner_adv.Value;
            app.totalTrials = value;
            app.ShowTrialSpinner_adv.Limits = [2 value+1];
        end

        % Value changed function: ThetaLRSpinner_adv
        function ThetaLRSpinner_advValueChanged(app, event)
            value = app.ThetaLRSpinner_adv.Value;
            app.thetaLR = value;
        end

        % Value changed function: PriorMeanLRSpinner_adv
        function PriorMeanLRSpinner_advValueChanged(app, event)
            value = app.PriorMeanLRSpinner_adv.Value;
            app.priorMeanLR = value;
        end

        % Value changed function: LeizureListBox_adv
        function LeizureListBox_advValueChanged(app, event)
            value = app.LeizureListBox_adv.Value;
            app.lesion = value;
        end

        % Size changed function: AdvancedTab_2
        function AdvancedTab_2SizeChanged(app, event)
            position = app.AdvancedTab_2.Position; 
        end

        % Selection changed function: ShowPlotButtonGroup_adv
        function ShowPlotButtonGroup_advSelectionChanged(app, event)
            epsilonData = app.out_epsilons;
            phiData = app.out_phis;
            priorMeanData = app.out_priors ; 
            plotAdvanedResults(app, priorMeanData, phiData, epsilonData)
            
        end

        % Selection changed function: PlotButtonGroup_ex
        function PlotButtonGroup_exSelectionChanged(app, event)
            %plot for example case
            value = app.PlotButtonGroup_ex.SelectedObject.Text;
            if strcmp(value, "Prior")
                title(app.UIAxes_ex, "Prior ")
                xlabel(app.UIAxes_ex, {"Trials"})
                ylabel(app.UIAxes_ex, ["Prior Mean"; ""])
            end
            if strcmp(value, "Phi")
                title(app.UIAxes_ex, {"Phi"})
                xlabel(app.UIAxes_ex, "Time Units")
                ylabel(app.UIAxes_ex, "Posterior P(\Phi|u)")
            end
            if strcmp(value, "Epsilon")
                title(app.UIAxes_ex, {"Epsilon"})
                xlabel(app.UIAxes_ex, "Time Units")
                ylabel(app.UIAxes_ex, "Epsilon")
            end
            epsilonData = app.out_epsilons_ex;
            phiData = app.out_phis_ex;
            priorMeanData = app.out_priors_ex; 
            trial = app.currentTrial;
            whichPlot = app.PlotButtonGroup_ex.SelectedObject.Text;
            if strcmp(whichPlot, "Epsilon")
                x = epsilonData{trial}{1};
                y = epsilonData{trial}{2}{2};
                dn = '\epsilon_{' ;
                
            elseif strcmp(whichPlot, "Phi")
                x = phiData{trial}{1};
                y = phiData{trial}{2}{2};
                dn = '\phi_{' ;
            else
                x = priorMeanData{trial}{1};
                y = priorMeanData{trial}{2};
                dn = 'prior_{';
            end
            cla(app.UIAxes_ex)
            for observation = 1:4
                hold(app.UIAxes_ex, 'on' )
                plot(app.UIAxes_ex,x, y(observation, :), ... 
                     'DisplayName', [dn num2str(2) ', ' num2str(observation) '}'])
            end
            legend(app.UIAxes_ex)
            hold(app.UIAxes_ex, 'off' )
        end

        % Value changed function: ObservedInputEditField_adv
        function ObservedInputEditField_advValueChanged(app, event)
            value = app.ObservedInputEditField_adv.Value;
            app.input_observed = value;
        end

        % Value changed function: PriorSigmaEditField_adv
        function PriorSigmaEditField_advValueChanged(app, event)
            value = app.PriorSigmaEditField_adv.Value;
            app.input_sigma =  value;
        end

        % Value changed function: ShowLayerSpinner_adv
        function LayerSlider_ValueChanged(app, event)
            if (app.ShowLayerSpinner_adv.Value <= app.totalLayers)
                app.currentLayer = app.ShowLayerSpinner_adv.Value;
            else
                app.ShowLayerSpinner_adv.Value = app.totalLayers;
            end
            
            plotAdvanedResults(app, app.out_priors, app.out_phis, app.out_epsilons)
        end

        % Value changed function: RunButton_adv
        function RunButton_advValueChanged(app, event)
            % ----- plot for advanced case -----
            set(app.RunButton_adv,'Text','Running...')
            set(app.PressRunLamp,'Color','red')
            drawnow;
            
            totalLayers = app.TotalLayersSpinner_adv.Value
            app.totalLayers = totalLayers;
            app.ShowLayerSpinner_adv.Limits = [1 totalLayers];
%             if totalLayers == 2
%                 app.ShowLayerSpinner_adv.Limits = [1 totalLayers+1];
%             else
%                 app.ShowLayerSpinner_adv.Limits = [1 totalLayers];
%             end
            
            timeUnits = app.TimeUnitsSpinner_adv.Value;
            app.timeUnits =timeUnits;
            
            deltaT = app.tSpinner_adv.Value;
            app.deltaT = deltaT;
            
            totalTrials = app.TrialsSpinner_adv.Value;
            app.totalTrials = totalTrials;
            if totalTrials == 2
                app.ShowTrialSpinner_adv.Limits = [2 totalTrials+1];
            else
                app.ShowTrialSpinner_adv.Limits = [2 totalTrials];
            end
            
            sigmaLR = app.SigmaLRSpinner_adv.Value;
            app.sigmaLR = sigmaLR;
            
            thetaLR = app.ThetaLRSpinner_adv.Value;
            app.thetaLR = thetaLR;
            
            priorMeanLR = app.PriorMeanLRSpinner_adv.Value;
            app.priorMeanLR = priorMeanLR;
            
            lesion = app.LeizureListBox_adv.Value;
            app.lesion = lesion;
            
            priorMean = str2num(app.PriorMeanInputEditField_adv.Value);
            app.input_prior = priorMean;
            
            priorVariance = str2num(app.PriorSigmaEditField_adv.Value);
            app.input_sigma = priorVariance;
            
            observedImages = {str2num(app.ObservedInputEditField_adv.Value)};
            app.input_observed = observedImages;
            
            observationFunction = app.ObservationFunctionDropDown_adv.Value;
            app.observation_funciton = observationFunction;
            
            [epsilonData, phiData, priorMeanData] = multi_layered_pixels_advanced(totalLayers, timeUnits, ...
    deltaT, totalTrials, sigmaLR, thetaLR, priorMeanLR, lesion, priorMean, priorVariance, observedImages, ...
    observationFunction);
            
            app.out_epsilons = epsilonData;
            app.out_phis = phiData;
            app.out_priors = priorMeanData;
            plotAdvanedResults(app, priorMeanData, phiData, epsilonData)
            
            set(app.RunButton_adv,'Text','Run')
            set(app.RunButton_adv,'Value', 0)
            set(app.PressRunLamp,'Color','green')
        end

        % Value changed function: TotalLayersSpinner_adv
        function TotalLayersSpinner_advValueChanged(app, event)
            value = app.TotalLayersSpinner_adv.Value;
            app.totalLayers = value;
            app.ShowLayerSpinner_adv.Limits = [2 value+1];
        end

        % Value changed function: ObservationFunctionDropDown_adv
        function ObservationFunctionDropDown_advValueChanged(app, event)
            value = app.ObservationFunctionDropDown_adv.Value;
            app.observation_funciton = value;
        end

        % Value changed function: PriorMeanInputEditField_adv
        function PriorMeanInputEditField_advValueChanged(app, event)
            value = app.PriorMeanInputEditField_adv.Value;
            app.input_prior = value;
        end

        % Value changed function: ShowLayerSpinner_ex
        function ShowLayerSpinner_exValueChanged(app, event)
            value = app.ShowLayerSpinner_ex.Value;
            app.currentTrial = value;
            PlotButtonGroup_exSelectionChanged(app, event);
        end

        % Callback function
        function TotalLayersSpinner_exValueChanged(app, event)
            value = app.TotalLayersSpinner_ex.Value;
            app.numberOfLayers = value;
            app.ShowLayerSpinner_ex.Limits = [1 value];
        end

        % Value changed function: ShowTrialSpinner_adv
        function ShowTrialSpinner_advValueChanged(app, event)
            if (app.ShowTrialSpinner_adv.Value <= app.totalTrials)
                app.currentTrial = app.ShowTrialSpinner_adv.Value;
            else
                app.ShowTrialSpinner_adv.Value = app.totalTrials;
            end
            plotAdvanedResults(app, app.out_priors, app.out_phis, app.out_epsilons)
        end

        % Value changed function: RunButton_ex
        function Run_ExampleValueChanged(app, event)
            numLayers = 2;
            app.currentTrial = app.ShowLayerSpinner_ex.Value; 
            trial = app.ShowLayerSpinner_ex.Value;
            [epsilonData, phiData, priorMeanData] = multi_layered_pixels_example(numLayers);
            app.out_epsilons_ex = epsilonData;
            app.out_phis_ex = phiData;
            app.out_priors_ex = priorMeanData; 
            whichPlot = app.PlotButtonGroup_ex.SelectedObject.Text;
            if strcmp(whichPlot, "Epsilon")
                x = epsilonData{trial}{1};
                y = epsilonData{trial}{2}{2};
                dn = '\epsilon_{' ;
                
            elseif strcmp(whichPlot, "Phi")
                x = phiData{trial}{1};
                y = phiData{trial}{2}{2};
                dn = '\phi_{' ;
            else
                x = priorMeanData{trial}{1};
                y = priorMeanData{trial}{2};
                dn = 'prior_{';
            end
            for observation = 1:4
                hold(app.UIAxes_ex, 'on' )
                plot(app.UIAxes_ex,x, y(observation, :), ... 
                     'DisplayName', [dn num2str(2) ', ' num2str(observation) '}'])
            end
            legend(app.UIAxes_ex)
            hold(app.UIAxes_ex, 'off' )
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ExampleTab = uitab(app.TabGroup);
            app.ExampleTab.Title = 'Example';
            app.RunButton_ex = uibutton(app.ExampleTab, 'state');
            app.RunButton_ex.ValueChangedFcn = createCallbackFcn(app, @Run_ExampleValueChanged, true);
            app.RunButton_ex.Text = {'Run '; ''};
            app.PlotButtonGroup_ex = uibuttongroup(app.ExampleTab);
            app.PlotButtonGroup_ex.SelectionChangedFcn = createCallbackFcn(app, @PlotButtonGroup_exSelectionChanged, true);
            app.PlotButtonGroup_ex.Title = 'Plot';
            app.PriorButton_ex = uiradiobutton(app.PlotButtonGroup_ex);
            app.PriorButton_ex.Text = 'Prior';
            app.PriorButton_ex.Value = true;
            app.PhiButton_ex = uiradiobutton(app.PlotButtonGroup_ex);
            app.PhiButton_ex.Text = 'Phi';
            app.EpsilonButton_ex = uiradiobutton(app.PlotButtonGroup_ex);
            app.EpsilonButton_ex.Text = 'Epsilon';
            app.ShowTrialLabel = uilabel(app.ExampleTab);
            app.ShowTrialLabel.Text = 'Show Trial';
            app.ShowLayerSpinner_ex = uispinner(app.ExampleTab);
            app.ShowLayerSpinner_ex.Step = 50;
            app.ShowLayerSpinner_ex.Limits = [2 1000];
            app.ShowLayerSpinner_ex.ValueChangedFcn = createCallbackFcn(app, @ShowLayerSpinner_exValueChanged, true);
            app.ShowLayerSpinner_ex.Value = 2;
            app.TextArea = uitextarea(app.ExampleTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Click on "Run" before you change "Show Trials" or "Plot".'};
            app.UIAxes_ex = uiaxes(app.ExampleTab);
            title(app.UIAxes_ex, 'Results')
            xlabel(app.UIAxes_ex, {''; ''})
            ylabel(app.UIAxes_ex, '')
            app.AdvancedTab_2 = uitab(app.TabGroup);
            app.AdvancedTab_2.SizeChangedFcn = createCallbackFcn(app, @AdvancedTab_2SizeChanged, true);
            app.AdvancedTab_2.Title = 'Advanced';
            app.RunButton_adv = uibutton(app.AdvancedTab_2, 'state');
            app.RunButton_adv.ValueChangedFcn = createCallbackFcn(app, @RunButton_advValueChanged, true);
            app.RunButton_adv.Text = {'Run '; ''};
            app.Description_adv = uitextarea(app.AdvancedTab_2);
            app.Description_adv.Editable = 'off';
            app.Description_adv.Value = {'Click on "Run" before you change "Show Trials" or "Plot".'; 'Please refer to the report to get an introduction about all the inputs.'};
            app.TotalLayersSpinner_3Label = uilabel(app.AdvancedTab_2);
            app.TotalLayersSpinner_3Label.Text = 'Total Layers';
            app.TotalLayersSpinner_adv = uispinner(app.AdvancedTab_2);
            app.TotalLayersSpinner_adv.Limits = [2 100];
            app.TotalLayersSpinner_adv.ValueChangedFcn = createCallbackFcn(app, @TotalLayersSpinner_advValueChanged, true);
            app.TotalLayersSpinner_adv.Value = 3;
            app.LesionLabel = uilabel(app.AdvancedTab_2);
            app.LesionLabel.Text = {'Lesion'; ''; ''};
            app.LeizureListBox_adv = uilistbox(app.AdvancedTab_2);
            app.LeizureListBox_adv.Items = {'None', 'Perturb Sigma', 'Loose Prior', 'Zero Pad Theta'};
            app.LeizureListBox_adv.ValueChangedFcn = createCallbackFcn(app, @LeizureListBox_advValueChanged, true);
            app.LeizureListBox_adv.Value = 'None';
            app.SigmaLRSpinner_2Label = uilabel(app.AdvancedTab_2);
            app.SigmaLRSpinner_2Label.Text = 'Sigma LR';
            app.SigmaLRSpinner_adv = uispinner(app.AdvancedTab_2);
            app.SigmaLRSpinner_adv.Step = 1e-05;
            app.SigmaLRSpinner_adv.Limits = [0 1];
            app.SigmaLRSpinner_adv.ValueChangedFcn = createCallbackFcn(app, @SigmaLRSpinnerValueChanged, true);
            app.SigmaLRSpinner_adv.Value = 1e-05;
            app.PriorMeanInputEditFieldLabel = uilabel(app.AdvancedTab_2);
            app.PriorMeanInputEditFieldLabel.Text = 'Prior Mean Input';
            app.PriorMeanInputEditField_adv = uieditfield(app.AdvancedTab_2, 'text');
            app.PriorMeanInputEditField_adv.ValueChangedFcn = createCallbackFcn(app, @PriorMeanInputEditField_advValueChanged, true);
            app.PriorMeanInputEditField_adv.Value = '[250; 50; 40; 255]';
            app.TimeUnitsSpinner_3Label = uilabel(app.AdvancedTab_2);
            app.TimeUnitsSpinner_3Label.Text = 'Time Units';
            app.TimeUnitsSpinner_adv = uispinner(app.AdvancedTab_2);
            app.TimeUnitsSpinner_adv.Limits = [1 100];
            app.TimeUnitsSpinner_adv.ValueChangedFcn = createCallbackFcn(app, @TimeUnitsSpinner_advValueChanged, true);
            app.TimeUnitsSpinner_adv.Value = 10;
            app.tLabel = uilabel(app.AdvancedTab_2);
            app.tLabel.Text = 'Δt';
            app.tSpinner_adv = uispinner(app.AdvancedTab_2);
            app.tSpinner_adv.Step = 1e-05;
            app.tSpinner_adv.Limits = [1e-05 0.1];
            app.tSpinner_adv.ValueChangedFcn = createCallbackFcn(app, @tSpinner_advValueChanged, true);
            app.tSpinner_adv.Value = 0.01;
            app.TrialsSpinnerLabel = uilabel(app.AdvancedTab_2);
            app.TrialsSpinnerLabel.Text = 'Trials';
            app.TrialsSpinner_adv = uispinner(app.AdvancedTab_2);
            app.TrialsSpinner_adv.Limits = [2 Inf];
            app.TrialsSpinner_adv.ValueChangedFcn = createCallbackFcn(app, @TrialsSpinner_advValueChanged, true);
            app.TrialsSpinner_adv.Value = 1000;
            app.UIAxes_adv = uiaxes(app.AdvancedTab_2);
            title(app.UIAxes_adv, 'Result')
            xlabel(app.UIAxes_adv, '')
            ylabel(app.UIAxes_adv, '')
            app.ThetaLRSpinnerLabel = uilabel(app.AdvancedTab_2);
            app.ThetaLRSpinnerLabel.Text = 'Theta LR';
            app.ThetaLRSpinner_adv = uispinner(app.AdvancedTab_2);
            app.ThetaLRSpinner_adv.Step = 1e-05;
            app.ThetaLRSpinner_adv.Limits = [0 1];
            app.ThetaLRSpinner_adv.ValueChangedFcn = createCallbackFcn(app, @ThetaLRSpinner_advValueChanged, true);
            app.PriorMeanLRSpinnerLabel = uilabel(app.AdvancedTab_2);
            app.PriorMeanLRSpinnerLabel.Text = 'Prior Mean LR';
            app.PriorMeanLRSpinner_adv = uispinner(app.AdvancedTab_2);
            app.PriorMeanLRSpinner_adv.Step = 0.01;
            app.PriorMeanLRSpinner_adv.Limits = [0 1];
            app.PriorMeanLRSpinner_adv.ValueChangedFcn = createCallbackFcn(app, @PriorMeanLRSpinner_advValueChanged, true);
            app.PriorMeanLRSpinner_adv.Value = 0.01;
            app.ShowPlotButtonGroup_adv = uibuttongroup(app.AdvancedTab_2);
            app.ShowPlotButtonGroup_adv.SelectionChangedFcn = createCallbackFcn(app, @ShowPlotButtonGroup_advSelectionChanged, true);
            app.ShowPlotButtonGroup_adv.Title = 'Show Plot';
            app.PriorButton_adv = uiradiobutton(app.ShowPlotButtonGroup_adv);
            app.PriorButton_adv.Text = 'Prior';
            app.PriorButton_adv.Value = true;
            app.PhiButton_adv = uiradiobutton(app.ShowPlotButtonGroup_adv);
            app.PhiButton_adv.Text = 'Phi';
            app.EpsilonButton_adv = uiradiobutton(app.ShowPlotButtonGroup_adv);
            app.EpsilonButton_adv.Text = 'Epsilon';
            app.ObservedInputEditFieldLabel = uilabel(app.AdvancedTab_2);
            app.ObservedInputEditFieldLabel.Text = 'Observed Input';
            app.ObservedInputEditField_adv = uieditfield(app.AdvancedTab_2, 'text');
            app.ObservedInputEditField_adv.ValueChangedFcn = createCallbackFcn(app, @ObservedInputEditField_advValueChanged, true);
            app.ObservedInputEditField_adv.Value = '[200; 70; 50; 190]';
            app.PriorVarianceLabel = uilabel(app.AdvancedTab_2);
            app.PriorVarianceLabel.Text = 'Prior Variance';
            app.PriorSigmaEditField_adv = uieditfield(app.AdvancedTab_2, 'text');
            app.PriorSigmaEditField_adv.ValueChangedFcn = createCallbackFcn(app, @PriorSigmaEditField_advValueChanged, true);
            app.PriorSigmaEditField_adv.Value = 'eye(4)';
            app.ObservationFunctionDropDownLabel = uilabel(app.AdvancedTab_2);
            app.ObservationFunctionDropDownLabel.Text = 'Observation Function';
            app.ObservationFunctionDropDown_adv = uidropdown(app.AdvancedTab_2);
            app.ObservationFunctionDropDown_adv.Items = {'Linear', 'Quadratic', 'Logarithmic'};
            app.ObservationFunctionDropDown_adv.ValueChangedFcn = createCallbackFcn(app, @ObservationFunctionDropDown_advValueChanged, true);
            app.ObservationFunctionDropDown_adv.Value = 'Linear';
            app.ShowLayerSpinnerLabel = uilabel(app.AdvancedTab_2);
            app.ShowLayerSpinnerLabel.Text = 'Show Layer';
            app.ShowLayerSpinner_adv = uispinner(app.AdvancedTab_2);
            app.ShowLayerSpinner_adv.Limits = [1 Inf];
            app.ShowLayerSpinner_adv.ValueChangedFcn = createCallbackFcn(app, @LayerSlider_ValueChanged, true);
            app.ShowLayerSpinner_adv.Value = 2;
            app.ShowTrialLabel_2 = uilabel(app.AdvancedTab_2);
            app.ShowTrialLabel_2.Text = 'Show Trial';
            app.ShowTrialSpinner_adv = uispinner(app.AdvancedTab_2);
            app.ShowTrialSpinner_adv.Step = 50;
            app.ShowTrialSpinner_adv.Limits = [2 3];
            app.ShowTrialSpinner_adv.ValueChangedFcn = createCallbackFcn(app, @ShowTrialSpinner_advValueChanged, true);
            app.ShowTrialSpinner_adv.Value = 2;
            app.PressRunLamp = uilamp(app.AdvancedTab_2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PCFramework

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end